classdef start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        quarterFIXButton               matlab.ui.control.Button
        halfFIXButton                  matlab.ui.control.Button
        fullFIXButton                  matlab.ui.control.Button
        fullMOVButton                  matlab.ui.control.Button
        RampModeLabel                  matlab.ui.control.Label
        ModelconfigurationLabel        matlab.ui.control.Label
        rampButton                     matlab.ui.control.Button
        sineButton                     matlab.ui.control.Button
        randomButton                   matlab.ui.control.Button
        StructuralforcessensingSwitchLabel  matlab.ui.control.Label
        StructuralforcessensingSwitch  matlab.ui.control.Switch
        SimulationtimeEditFieldLabel   matlab.ui.control.Label
        SimulationtimeEditField        matlab.ui.control.EditField
        RampslopeEditFieldLabel        matlab.ui.control.Label
        RampslopeEditField             matlab.ui.control.EditField
        SineModeLabel                  matlab.ui.control.Label
        SineamplitudeEditFieldLabel    matlab.ui.control.Label
        SineamplitudeEditField         matlab.ui.control.EditField
        RandomModeLabel                matlab.ui.control.Label
        randomamplitudeEditFieldLabel  matlab.ui.control.Label
        randomamplitudeEditField       matlab.ui.control.EditField
        SinefrequencyEditFieldLabel    matlab.ui.control.Label
        SinefrequencyEditField         matlab.ui.control.EditField
        randomharshnessEditFieldLabel  matlab.ui.control.Label
        randomharshnessEditField       matlab.ui.control.EditField
        ResetButton                    matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ExcessivejounceLampLabel       matlab.ui.control.Label
        ExcessivejounceLamp            matlab.ui.control.Lamp
        Lamp_ramp                      matlab.ui.control.Lamp
        Lamp_sine                      matlab.ui.control.Lamp
        Lamp_random                    matlab.ui.control.Lamp
        Lamp_quarter_FIX               matlab.ui.control.Lamp
        Lamp_half_FIX                  matlab.ui.control.Lamp
        Lamp_full_FIX                  matlab.ui.control.Lamp
        Lamp_full_MOV                  matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        Smi;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base', 'clear')
            evalin('base', 'clc')
            
            addpath('Parameters');
            DATA;
            app.Smi=Smi;
            assignin('base','Smi',app.Smi);
            
            app.Lamp_ramp.Color='red';
            
            app.SimulationtimeEditField.Value=num2str(app.Smi.general.simulation_time);
            app.RampslopeEditField.Value=num2str(app.Smi.general.ramp_slope);
            app.SineamplitudeEditField.Value=num2str(app.Smi.general.sine_amplitude);
            app.SinefrequencyEditField.Value=num2str(app.Smi.general.sine_frequency);
            app.randomamplitudeEditField.Value=num2str(app.Smi.general.random_amplitude);
            app.randomharshnessEditField.Value=num2str(app.Smi.general.random_harshness);
           
        end

        % Button pushed function: quarterFIXButton
        function quarterFIXButtonPushed(app, event)
            app.Lamp_quarter_FIX.Color = 'red';
            app.Lamp_half_FIX.Color = 'green';
            app.Lamp_full_FIX.Color = 'green';
            app.Lamp_full_MOV.Color = 'green';
            
            quarter_FIX;
            
            if strcmp(get_param('Parametrica/FIXED CHASSIS/BODY/FL HARDPOINTS/A forces sensing', 'Commented'),'on')
                app.StructuralforcessensingSwitch.Value=' Off';
            else
                app.StructuralforcessensingSwitch.Value=' On';
            end
            

        end

        % Button pushed function: halfFIXButton
        function halfFIXButtonPushed(app, event)
            app.Lamp_quarter_FIX.Color = 'green';
            app.Lamp_half_FIX.Color = 'red';
            app.Lamp_full_FIX.Color = 'green';
            app.Lamp_full_MOV.Color = 'green';
            
            half_FIX;   
            
            if strcmp(get_param('Parametrica/FIXED CHASSIS/BODY/FL HARDPOINTS/A forces sensing', 'Commented'),'on')
                app.StructuralforcessensingSwitch.Value=' Off';
            else
                app.StructuralforcessensingSwitch.Value=' On';
            end
           
        end

        % Button pushed function: fullFIXButton
        function fullFIXButtonPushed(app, event)
            app.Lamp_quarter_FIX.Color = 'green';
            app.Lamp_half_FIX.Color = 'green';
            app.Lamp_full_FIX.Color = 'red';
            app.Lamp_full_MOV.Color = 'green';
            
            full_FIX;
            
            if strcmp(get_param('Parametrica/FIXED CHASSIS/BODY/FL HARDPOINTS/A forces sensing', 'Commented'),'on')
                app.StructuralforcessensingSwitch.Value=' Off';
            else
                app.StructuralforcessensingSwitch.Value=' On';
            end
            
            app.Lamp_quarter_FIX.Color = 'green';
            app.Lamp_half_FIX.Color = 'green';
            app.Lamp_full_FIX.Color = 'red';
            app.Lamp_full_MOV.Color = 'green';
        end

        % Button pushed function: fullMOVButton
        function fullMOVButtonPushed(app, event)
            app.Lamp_quarter_FIX.Color = 'green';
            app.Lamp_half_FIX.Color = 'green';
            app.Lamp_full_FIX.Color = 'green';
            app.Lamp_full_MOV.Color = 'red'; 
            full_MOV;
             
            if strcmp(get_param('Parametrica/FIXED CHASSIS/BODY/FL HARDPOINTS/A forces sensing', 'Commented'),'on')
                app.StructuralforcessensingSwitch.Value=' Off';
            else
                app.StructuralforcessensingSwitch.Value=' On';
            end
             
            
        end

        % Button pushed function: rampButton
        function rampButtonPushed(app, event)
            app.Smi.general.jounce_mode=1;
            assignin('base','Smi',app.Smi);
            
            app.Lamp_ramp.Color='red';
            app.Lamp_sine.Color='green';
            app.Lamp_random.Color='green';
            
            if app.Smi.general.ramp_slope*app.Smi.general.simulation_time>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
        end

        % Button pushed function: sineButton
        function sineButtonPushed(app, event)
            app.Smi.general.jounce_mode=2;
            assignin('base','Smi',app.Smi);
            
            app.Lamp_ramp.Color='green';
            app.Lamp_sine.Color='red';
            app.Lamp_random.Color='green';
            
            if app.Smi.general.sine_amplitude>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
        end

        % Button pushed function: randomButton
        function randomButtonPushed(app, event)
            app.Smi.general.jounce_mode=3;
            assignin('base','Smi',app.Smi);
            
            app.Lamp_ramp.Color='green';
            app.Lamp_sine.Color='green';
            app.Lamp_random.Color='red';
            
            if app.Smi.general.random_amplitude>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
            
        end

        % Value changed function: SimulationtimeEditField
        function SimulationtimeEditFieldValueChanged(app, event)
            app.Smi.general.simulation_time = str2double(app.SimulationtimeEditField.Value);
            assignin('base','Smi',app.Smi);
            
            if app.Smi.general.jounce_mode==1
            if app.Smi.general.ramp_slope*app.Smi.general.simulation_time>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
            end
        end

        % Value changed function: StructuralforcessensingSwitch
        function StructuralforcessensingSwitchValueChanged(app, event)
            value = app.StructuralforcessensingSwitch.Value;
            assignin('base','value',value)
            if strcmp('Off',value)
                disable_structural_force_sensing;
                app.EditField.Value='Off';
            else
                enable_structural_force_sensing;
                app.EditField.Value='On';
            end
        end

        % Value changed function: RampslopeEditField
        function RampslopeEditFieldValueChanged(app, event)
            app.Smi.general.ramp_slope = str2double(app.RampslopeEditField.Value);
            assignin('base','Smi',app.Smi);
            
            if  app.Smi.general.jounce_mode==1
            if app.Smi.general.ramp_slope*app.Smi.general.simulation_time>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
            end
        end

        % Value changed function: SineamplitudeEditField
        function SineamplitudeEditFieldValueChanged(app, event)
            app.Smi.general.sine_amplitude = str2double(app.SineamplitudeEditField.Value);
            assignin('base','Smi',app.Smi);
            
            if  app.Smi.general.jounce_mode==2
            if app.Smi.general.sine_amplitude>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
            end
        end

        % Value changed function: SinefrequencyEditField
        function SinefrequencyEditFieldValueChanged(app, event)
            app.Smi.general.sine_frequency = str2double(app.SinefrequencyEditField.Value);
            assignin('base','Smi',app.Smi);
        end

        % Value changed function: randomamplitudeEditField
        function randomamplitudeEditFieldValueChanged(app, event)
            app.Smi.general.random_amplitude = str2double(app.randomamplitudeEditField.Value);
            assignin('base','Smi',app.Smi);
            
            if  app.Smi.general.jounce_mode==3
            if app.Smi.general.random_amplitude>50
                app.ExcessivejounceLamp.Color='red';
            else
                app.ExcessivejounceLamp.Color='green';
            end
            end
        end

        % Value changed function: randomharshnessEditField
        function randomharshnessEditFieldValueChanged(app, event)
            app.Smi.general.random_harshness = str2double(app.randomharshnessEditField.Value);
            assignin('base','Smi',app.Smi);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            DATA;
            app.Smi=Smi;
            assignin('base','Smi',app.Smi);     
            
            app.SimulationtimeEditField.Value=num2str(app.Smi.general.simulation_time);
            app.RampslopeEditField.Value=num2str(app.Smi.general.ramp_slope);
            app.SineamplitudeEditField.Value=num2str(app.Smi.general.sine_amplitude);
            app.SinefrequencyEditField.Value=num2str(app.Smi.general.sine_frequency);
            app.randomamplitudeEditField.Value=num2str(app.Smi.general.random_amplitude);
            app.randomharshnessEditField.Value=num2str(app.Smi.general.random_harshness);
            
            app.ExcessivejounceLamp.Color='green';
            
            app.Lamp_ramp.Color='red';
            app.Lamp_sine.Color='green';
            app.Lamp_random.Color='green';

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            sim('Parametrica');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('Parametrica', 'SimulationCommand', 'stop')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.quarterFIXButton = uibutton(app.UIFigure, 'push');
            app.quarterFIXButton.ButtonPushedFcn = createCallbackFcn(app, @quarterFIXButtonPushed, true);
            app.quarterFIXButton.Text = 'quarter FIX';
            app.halfFIXButton = uibutton(app.UIFigure, 'push');
            app.halfFIXButton.ButtonPushedFcn = createCallbackFcn(app, @halfFIXButtonPushed, true);
            app.halfFIXButton.Text = 'half FIX';
            app.fullFIXButton = uibutton(app.UIFigure, 'push');
            app.fullFIXButton.ButtonPushedFcn = createCallbackFcn(app, @fullFIXButtonPushed, true);
            app.fullFIXButton.Text = 'full FIX';
            app.fullMOVButton = uibutton(app.UIFigure, 'push');
            app.fullMOVButton.ButtonPushedFcn = createCallbackFcn(app, @fullMOVButtonPushed, true);
            app.fullMOVButton.Text = 'full MOV';
            app.RampModeLabel = uilabel(app.UIFigure);
            app.RampModeLabel.Text = 'Ramp Mode';
            app.ModelconfigurationLabel = uilabel(app.UIFigure);
            app.ModelconfigurationLabel.Text = 'Model configuration';
            app.rampButton = uibutton(app.UIFigure, 'push');
            app.rampButton.ButtonPushedFcn = createCallbackFcn(app, @rampButtonPushed, true);
            app.rampButton.Text = 'ramp';
            app.sineButton = uibutton(app.UIFigure, 'push');
            app.sineButton.ButtonPushedFcn = createCallbackFcn(app, @sineButtonPushed, true);
            app.sineButton.Text = 'sine';
            app.randomButton = uibutton(app.UIFigure, 'push');
            app.randomButton.ButtonPushedFcn = createCallbackFcn(app, @randomButtonPushed, true);
            app.randomButton.Text = 'random';
            app.StructuralforcessensingSwitchLabel = uilabel(app.UIFigure);
            app.StructuralforcessensingSwitchLabel.Text = 'Structural forces sensing';
            app.StructuralforcessensingSwitch = uiswitch(app.UIFigure, 'slider');
            app.StructuralforcessensingSwitch.ValueChangedFcn = createCallbackFcn(app, @StructuralforcessensingSwitchValueChanged, true);
            app.SimulationtimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.UIFigure, 'text');
            app.SimulationtimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeEditFieldValueChanged, true);
            app.RampslopeEditFieldLabel = uilabel(app.UIFigure);
            app.RampslopeEditFieldLabel.Text = 'Ramp slope';
            app.RampslopeEditField = uieditfield(app.UIFigure, 'text');
            app.RampslopeEditField.ValueChangedFcn = createCallbackFcn(app, @RampslopeEditFieldValueChanged, true);
            app.SineModeLabel = uilabel(app.UIFigure);
            app.SineModeLabel.Text = 'Sine Mode';
            app.SineamplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.SineamplitudeEditFieldLabel.Text = 'Sine amplitude';
            app.SineamplitudeEditField = uieditfield(app.UIFigure, 'text');
            app.SineamplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @SineamplitudeEditFieldValueChanged, true);
            app.RandomModeLabel = uilabel(app.UIFigure);
            app.RandomModeLabel.Text = 'Random Mode';
            app.randomamplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.randomamplitudeEditFieldLabel.Text = 'random amplitude';
            app.randomamplitudeEditField = uieditfield(app.UIFigure, 'text');
            app.randomamplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @randomamplitudeEditFieldValueChanged, true);
            app.SinefrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SinefrequencyEditFieldLabel.Text = 'Sine frequency';
            app.SinefrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.SinefrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SinefrequencyEditFieldValueChanged, true);
            app.randomharshnessEditFieldLabel = uilabel(app.UIFigure);
            app.randomharshnessEditFieldLabel.Text = 'random harshness';
            app.randomharshnessEditField = uieditfield(app.UIFigure, 'text');
            app.randomharshnessEditField.ValueChangedFcn = createCallbackFcn(app, @randomharshnessEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExcessivejounceLampLabel = uilabel(app.UIFigure);
            app.ExcessivejounceLampLabel.Text = 'Excessive jounce';
            app.ExcessivejounceLamp = uilamp(app.UIFigure);
            app.Lamp_ramp = uilamp(app.UIFigure);
            app.Lamp_sine = uilamp(app.UIFigure);
            app.Lamp_random = uilamp(app.UIFigure);
            app.Lamp_quarter_FIX = uilamp(app.UIFigure);
            app.Lamp_half_FIX = uilamp(app.UIFigure);
            app.Lamp_full_FIX = uilamp(app.UIFigure);
            app.Lamp_full_MOV = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end